classdef ejercicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GrabarButton_2          matlab.ui.control.Button
        PararButton             matlab.ui.control.Button
        ReproducirButton        matlab.ui.control.Button
        PausarButton            matlab.ui.control.Button
        EfectosButtonGroup      matlab.ui.container.ButtonGroup
        NingunoButton           matlab.ui.control.RadioButton
        EcoButton               matlab.ui.control.RadioButton
        FlangingButton          matlab.ui.control.RadioButton
        PlanningButton          matlab.ui.control.RadioButton
        ReververacionButton     matlab.ui.control.RadioButton
        TremoloButton           matlab.ui.control.RadioButton
        ECOKnobLabel            matlab.ui.control.Label
        ECOKnob                 matlab.ui.control.Knob
        ReververacionKnobLabel  matlab.ui.control.Label
        ReververacionKnob       matlab.ui.control.Knob
        TremoloKnobLabel        matlab.ui.control.Label
        TremoloKnob             matlab.ui.control.Knob
        DuracionTextAreaLabel   matlab.ui.control.Label
        DuracionTextArea        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarButton_2
        function GrabarButton_2Pushed(app, event)
            global audioObject
            global ms
            audioObject = audiorecorder;
            %duration = str2double(app.DuracionTextArea.Value{1});
            ms = msgbox('Grabando...');
            record(audioObject);
            %recordblocking(audioObject, duration);
            %delete(ms);
            
            assignin('base','audioObject', audioObject);
            %msgbox('Grabado exitosamente!');
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            global audioObject;
            audioObject = evalin('base','audioObject')
                y = getaudiodata(audioObject);
                Fs = audioObject.SampleRate;
                %multiplicador = app.ECOKnob.Value;
                sound(y);
                %sound(y, Fs*multiplicador);
            play(audioObject);
        end

        % Selection changed function: EfectosButtonGroup
        function EfectosButtonGroupSelectionChanged(app, event)
            selectedButton = app.EfectosButtonGroup.SelectedObject;
            
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            global audioObject;
            global ms 
            delete(ms);
            msgbox('Grabado exitosamente!');
            stop(audioObject);
        end

        % Value changed function: DuracionTextArea
        function DuracionTextAreaValueChanged(app, event)
            global value;
            value = app.DuracionTextArea.Value;
            
        end

        % Button pushed function: PausarButton
        function PausarButtonPushed(app, event)
            global audioObject;
            if(isrecording(audioObject))
                pause(audioObject);
            else
                resume(audioObject); 
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GrabarButton_2 = uibutton(app.UIFigure, 'push');
            app.GrabarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GrabarButton_2Pushed, true);
            app.GrabarButton_2.Text = 'Grabar';
            app.PararButton = uibutton(app.UIFigure, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.ReproducirButton = uibutton(app.UIFigure, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.PausarButton = uibutton(app.UIFigure, 'push');
            app.PausarButton.ButtonPushedFcn = createCallbackFcn(app, @PausarButtonPushed, true);
            app.PausarButton.Text = 'Pausar';
            app.EfectosButtonGroup = uibuttongroup(app.UIFigure);
            app.EfectosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EfectosButtonGroupSelectionChanged, true);
            app.EfectosButtonGroup.Title = 'Efectos';
            app.NingunoButton = uiradiobutton(app.EfectosButtonGroup);
            app.NingunoButton.Text = 'Ninguno';
            app.NingunoButton.Value = true;
            app.EcoButton = uiradiobutton(app.EfectosButtonGroup);
            app.EcoButton.Text = 'Eco';
            app.FlangingButton = uiradiobutton(app.EfectosButtonGroup);
            app.FlangingButton.Text = 'Flanging';
            app.PlanningButton = uiradiobutton(app.EfectosButtonGroup);
            app.PlanningButton.Text = 'Planning';
            app.ReververacionButton = uiradiobutton(app.EfectosButtonGroup);
            app.ReververacionButton.Text = 'Reververacion';
            app.TremoloButton = uiradiobutton(app.EfectosButtonGroup);
            app.TremoloButton.Text = 'Tremolo';
            app.ECOKnobLabel = uilabel(app.UIFigure);
            app.ECOKnobLabel.Text = 'ECO';
            app.ECOKnob = uiknob(app.UIFigure, 'continuous');
            app.ReververacionKnobLabel = uilabel(app.UIFigure);
            app.ReververacionKnobLabel.Text = 'Reververacion';
            app.ReververacionKnob = uiknob(app.UIFigure, 'continuous');
            app.TremoloKnobLabel = uilabel(app.UIFigure);
            app.TremoloKnobLabel.Text = 'Tremolo';
            app.TremoloKnob = uiknob(app.UIFigure, 'continuous');
            app.DuracionTextAreaLabel = uilabel(app.UIFigure);
            app.DuracionTextAreaLabel.Text = 'Duracion';
            app.DuracionTextArea = uitextarea(app.UIFigure);
            app.DuracionTextArea.ValueChangedFcn = createCallbackFcn(app, @DuracionTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ejercicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end